classdef Checklist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChecklistUIFigure            matlab.ui.Figure
        CheckItemOneCheckBox         matlab.ui.control.CheckBox
        Lamp                         matlab.ui.control.Lamp
        CheckItemTwoCheckBox         matlab.ui.control.CheckBox
        CheckItemThreeCheckBox       matlab.ui.control.CheckBox
        CheckItemFourCheckBox        matlab.ui.control.CheckBox
        CheckItemFiveCheckBox        matlab.ui.control.CheckBox
        CheckItemSixCheckBox         matlab.ui.control.CheckBox
        Lamp_2                       matlab.ui.control.Lamp
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_4                       matlab.ui.control.Lamp
        Lamp_5                       matlab.ui.control.Lamp
        Lamp_6                       matlab.ui.control.Lamp
        SafetyStartupChecklistLabel  matlab.ui.control.Label
        Knob                         matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        Property % Description
        CheckList = 0; 
        
    end
    
    methods (Access = private)
        
        function  isDone(app)
            disp(app.CheckList);
            if app.CheckList == 63
                app.Knob.Visible = 'on';
            else
                app.Knob.Visible = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckItemOneCheckBox
        function CheckItemOneCheckBoxValueChanged(app, event)
            if app.CheckItemOneCheckBox.Value
                app.Lamp.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 1, 'uint8');
            else
                app.Lamp.Enable = 'off';
                app.CheckList = bitset(app.CheckList, 1, 0);
            end
            
            isDone(app);
        end

        % Value changed function: CheckItemTwoCheckBox
        function CheckItemTwoCheckBoxValueChanged(app, event)
            if app.CheckItemTwoCheckBox.Value
                app.Lamp_2.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 2, 'uint8');
            else
                app.Lamp_2.Enable = 'off';
                app.CheckList = bitset(app.CheckList, 1, 0);
            end
            
            isDone(app);
        end

        % Value changed function: CheckItemThreeCheckBox
        function CheckItemThreeCheckBoxValueChanged(app, event)
            if app.CheckItemThreeCheckBox.Value
                app.Lamp_3.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 4, 'uint8');
            else
                app.Lamp_3.Enable = 'off';
                app.CheckList = bitset(app.CheckList, 1, 0);
            end    
            
            isDone(app);
        end

        % Value changed function: CheckItemFourCheckBox
        function CheckItemFourCheckBoxValueChanged(app, event)
            if app.CheckItemFourCheckBox.Value
                app.Lamp_4.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 8, 'uint8');
            else
                app.Lamp_4.Enable = 'off';
                app.CheckList = bitset(app.CheckList, 1, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: CheckItemFiveCheckBox
        function CheckItemFiveCheckBoxValueChanged(app, event)
            if app.CheckItemFiveCheckBox.Value
                app.Lamp_5.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 16, 'uint8');
            else
                app.Lamp_5.Enable = 'off';
                app.CheckList = bitset(app.CheckList, 1, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: CheckItemSixCheckBox
        function CheckItemSixCheckBoxValueChanged(app, event)
            if app.CheckItemSixCheckBox.Value
                app.Lamp_6.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 32, 'uint8');
            else
                app.Lamp_6.Enable = 'off';
                app.CheckList = bitset(app.CheckList, 1, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if app.Knob.Value == "On"
                Main
                close all;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChecklistUIFigure = uifigure('Visible', 'off');
            app.ChecklistUIFigure.Name = 'Checklist';
            app.CheckItemOneCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemOneCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemOneCheckBoxValueChanged, true);
            app.CheckItemOneCheckBox.Text = 'Check Item One';
            app.Lamp = uilamp(app.ChecklistUIFigure);
            app.Lamp.Enable = 'off';
            app.CheckItemTwoCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemTwoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemTwoCheckBoxValueChanged, true);
            app.CheckItemTwoCheckBox.Text = 'Check Item Two';
            app.CheckItemThreeCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemThreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemThreeCheckBoxValueChanged, true);
            app.CheckItemThreeCheckBox.Text = 'Check Item Three';
            app.CheckItemFourCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemFourCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemFourCheckBoxValueChanged, true);
            app.CheckItemFourCheckBox.Text = 'Check Item Four';
            app.CheckItemFiveCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemFiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemFiveCheckBoxValueChanged, true);
            app.CheckItemFiveCheckBox.Text = 'Check Item Five';
            app.CheckItemSixCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemSixCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemSixCheckBoxValueChanged, true);
            app.CheckItemSixCheckBox.Text = 'Check Item Six';
            app.Lamp_2 = uilamp(app.ChecklistUIFigure);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.ChecklistUIFigure);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.ChecklistUIFigure);
            app.Lamp_4.Enable = 'off';
            app.Lamp_5 = uilamp(app.ChecklistUIFigure);
            app.Lamp_5.Enable = 'off';
            app.Lamp_6 = uilamp(app.ChecklistUIFigure);
            app.Lamp_6.Enable = 'off';
            app.SafetyStartupChecklistLabel = uilabel(app.ChecklistUIFigure);
            app.SafetyStartupChecklistLabel.Text = 'Safety Startup Checklist';
            app.Knob = uiknob(app.ChecklistUIFigure, 'discrete');
            app.Knob.Items = {'Off', 'On'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Visible = 'off';
            % Show the figure after all components are created
            app.ChecklistUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Checklist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChecklistUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChecklistUIFigure)
        end
    end
end