classdef Main2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OrderTab                      matlab.ui.container.Tab
        CameraTab                     matlab.ui.container.Tab
        TableCameraPanel              matlab.ui.container.Panel
        TCam                          matlab.ui.control.UIAxes
        ConveyorCameraPanel           matlab.ui.container.Panel
        CCam                          matlab.ui.control.UIAxes
        Lamp                          matlab.ui.control.Lamp
        Gauge                         matlab.ui.control.LinearGauge
        MainTab                       matlab.ui.container.Tab
        Status                        matlab.ui.control.Lamp
        TableCamera                   matlab.ui.control.UIAxes
        ConveyorCamera                matlab.ui.control.UIAxes
        Notifications                 matlab.ui.container.Panel
        ROBOTSTATELabel               matlab.ui.control.Label
        TASKLabel                     matlab.ui.control.Label
        CONNECTIONLabel               matlab.ui.control.Label
        Controls                      matlab.ui.container.Panel
        POWERButton                   matlab.ui.control.Button
        VacuumLabel                   matlab.ui.control.Label
        ConveyorLabel                 matlab.ui.control.Label
        ConveyorDirectionButtonGroup  matlab.ui.container.ButtonGroup
        TowardsButton                 matlab.ui.control.RadioButton
        AwayButton                    matlab.ui.control.RadioButton
        SOLENOIDButton                matlab.ui.control.Button
        STARTButton                   matlab.ui.control.StateButton
        SolenoidButtonGroup           matlab.ui.container.ButtonGroup
        OffButton                     matlab.ui.control.RadioButton
        OnButton                      matlab.ui.control.RadioButton
        Knob                          matlab.ui.control.DiscreteKnob
        ConsolePanel                  matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            video_feed(app);
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            if app.STARTButton.Value
                app.STARTButton.Text = "STOP";
            else
                app.STARTButton.Text = "START";
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            if value == "OFF"
                app.Status.Color = [1, 0, 0];
            elseif value == "PAUSE"
                app.Status.Color = [0, 1, 1];
            elseif value == "ON"
                app.Status.Color = [0, 1, 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.TabGroup = uitabgroup(app.MainUIFigure);
            app.OrderTab = uitab(app.TabGroup);
            app.OrderTab.Title = 'Order';
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.TableCameraPanel = uipanel(app.CameraTab);
            app.TableCameraPanel.Title = 'Table Camera';
            app.TCam = uiaxes(app.TableCameraPanel);
            title(app.TCam, '')
            xlabel(app.TCam, '')
            ylabel(app.TCam, '')
            app.TCam.XTick = [0 1];
            app.TCam.XTickLabel = '';
            app.TCam.YTick = [0 1];
            app.TCam.YTickLabel = '';
            app.ConveyorCameraPanel = uipanel(app.CameraTab);
            app.ConveyorCameraPanel.Title = 'Conveyor Camera';
            app.CCam = uiaxes(app.ConveyorCameraPanel);
            title(app.CCam, '')
            xlabel(app.CCam, '')
            ylabel(app.CCam, '')
            app.CCam.XTick = [0 1];
            app.CCam.XTickLabel = '';
            app.CCam.YTick = [0 1];
            app.CCam.YTickLabel = '';
            app.Lamp = uilamp(app.CameraTab);
            app.Gauge = uigauge(app.CameraTab, 'linear');
            app.Gauge.MajorTicks = [0 25 50 75 100];
            app.Gauge.MinorTicks = [];
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Status = uilamp(app.MainTab);
            app.TableCamera = uiaxes(app.MainTab);
            title(app.TableCamera, '')
            xlabel(app.TableCamera, '')
            ylabel(app.TableCamera, {''; ''})
            app.TableCamera.XTick = [0 1];
            app.TableCamera.XTickLabel = '';
            app.TableCamera.YTick = [0 1];
            app.TableCamera.YTickLabel = '';
            app.ConveyorCamera = uiaxes(app.MainTab);
            title(app.ConveyorCamera, '')
            xlabel(app.ConveyorCamera, '')
            ylabel(app.ConveyorCamera, '')
            app.ConveyorCamera.XTick = [0 1];
            app.ConveyorCamera.XTickLabel = '';
            app.ConveyorCamera.YTick = [0 1];
            app.ConveyorCamera.YTickLabel = '';
            app.Notifications = uipanel(app.MainTab);
            app.Notifications.Title = 'Notifications';
            app.ROBOTSTATELabel = uilabel(app.Notifications);
            app.ROBOTSTATELabel.Text = 'ROBOT STATE:';
            app.TASKLabel = uilabel(app.Notifications);
            app.TASKLabel.Text = 'TASK:';
            app.CONNECTIONLabel = uilabel(app.Notifications);
            app.CONNECTIONLabel.Text = 'CONNECTION:';
            app.Controls = uipanel(app.MainTab);
            app.Controls.Title = 'Controls';
            app.POWERButton = uibutton(app.Controls, 'push');
            app.POWERButton.Text = 'POWER';
            app.VacuumLabel = uilabel(app.Controls);
            app.VacuumLabel.Text = 'Vacuum';
            app.ConveyorLabel = uilabel(app.Controls);
            app.ConveyorLabel.Text = 'Conveyor';
            app.ConveyorDirectionButtonGroup = uibuttongroup(app.Controls);
            app.ConveyorDirectionButtonGroup.Title = 'Conveyor Direction';
            app.TowardsButton = uiradiobutton(app.ConveyorDirectionButtonGroup);
            app.TowardsButton.Text = 'Towards';
            app.TowardsButton.Value = true;
            app.AwayButton = uiradiobutton(app.ConveyorDirectionButtonGroup);
            app.AwayButton.Text = 'Away';
            app.SOLENOIDButton = uibutton(app.Controls, 'push');
            app.SOLENOIDButton.Text = 'SOLENOID';
            app.STARTButton = uibutton(app.Controls, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.SolenoidButtonGroup = uibuttongroup(app.Controls);
            app.SolenoidButtonGroup.Title = 'Solenoid';
            app.OffButton = uiradiobutton(app.SolenoidButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.OnButton = uiradiobutton(app.SolenoidButtonGroup);
            app.OnButton.Text = {'On'; ''};
            app.Knob = uiknob(app.MainTab, 'discrete');
            app.Knob.Items = {'OFF', 'PAUSE', 'ON'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'ON';
            app.ConsolePanel = uipanel(app.MainTab);
            app.ConsolePanel.Title = 'Console';
            app.TextArea = uitextarea(app.ConsolePanel);
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end